classdef smt_generator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DimensionsPanel                 matlab.ui.container.Panel
        ALabel                          matlab.ui.control.Label
        A_dim_1                         matlab.ui.control.NumericEditField
        xEditFieldLabel                 matlab.ui.control.Label
        A_dim_2                         matlab.ui.control.NumericEditField
        BLabel                          matlab.ui.control.Label
        B_dim_1                         matlab.ui.control.NumericEditField
        xEditField_3Label               matlab.ui.control.Label
        B_dim_2                         matlab.ui.control.NumericEditField
        CLabel                          matlab.ui.control.Label
        C_dim_1                         matlab.ui.control.NumericEditField
        xEditField_4Label               matlab.ui.control.Label
        C_dim_2                         matlab.ui.control.NumericEditField
        KLabel                          matlab.ui.control.Label
        K_dim_1                         matlab.ui.control.NumericEditField
        xEditField_5Label               matlab.ui.control.Label
        K_dim_2                         matlab.ui.control.NumericEditField
        GenerateSMTLIBv2Button          matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        ATab                            matlab.ui.container.Tab
        InputmatrixATextAreaLabel       matlab.ui.control.Label
        Input_A                         matlab.ui.control.TextArea
        BTab                            matlab.ui.container.Tab
        InputmatrixBTextAreaLabel       matlab.ui.control.Label
        Input_B                         matlab.ui.control.TextArea
        KTab                            matlab.ui.container.Tab
        InputmatrixKTextAreaLabel       matlab.ui.control.Label
        Input_K                         matlab.ui.control.TextArea
        CTab                            matlab.ui.container.Tab
        InputmatrixCTextAreaLabel       matlab.ui.control.Label
        Input_C                         matlab.ui.control.TextArea
        OutputTextArea                  matlab.ui.control.TextArea
        InputorderButtonGroup           matlab.ui.container.ButtonGroup
        RowmajorButton                  matlab.ui.control.RadioButton
        ColumnmajorButton               matlab.ui.control.RadioButton
        InstructionsPanel               matlab.ui.container.Panel
        ThistoolgeneratesSMTLIBv2Label  matlab.ui.control.Label
        Output_A                        matlab.ui.control.Table
        Output_B                        matlab.ui.control.Table
        Output_K                        matlab.ui.control.Table
        Output_C                        matlab.ui.control.Table
        ALabel_2                        matlab.ui.control.Label
        BLabel_2                        matlab.ui.control.Label
        KLabel_2                        matlab.ui.control.Label
        CLabel_2                        matlab.ui.control.Label
        CopytoclipboardButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        A % Description
        B
        K
        C
    end
    
    methods (Access = private)
        
        function matrix = parse_matrix(app, text, dims)
            if (isempty(text))
                vector = 0;
            else
                %text(~isstrprop(text, 'digit')) = ' ';
                text = strrep(text, '[', ' ');
                text = strrep(text, ']', ' ');
                text = strrep(text, ',', ' ');
                text = strrep(text, ';', ' ');
                text = strrep(text, '(', ' ');
                text = strrep(text, ')', ' ');
                vector = str2double(strsplit(text));
                vector(isnan(vector)) = [];
            end
            
            n_input = numel(vector);
            n_expected = prod(dims);
            
            if (n_input < n_expected)
                vector = [vector, zeros(1, n_expected-n_input)];
            elseif (n_input > n_expected)
                vector = vector(1:n_expected);
            end
            
            if (app.RowmajorButton.Value == true)
                matrix = reshape(vector, flip(dims)).';
            else
                matrix = reshape(vector, dims);
            end
            
        end
        
        function refresh_output(app, event)
            Input_AValueChanged(app, event);
            Input_BValueChanged(app, event);
            Input_KValueChanged(app, event);
            Input_CValueChanged(app, event);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Output_A.ColumnName = {};
            app.Output_B.ColumnName = {};
            app.Output_K.ColumnName = {};
            app.Output_C.ColumnName = {};
            
            app.OutputTextArea.Editable = false;
        end

        % Value changed function: A_dim_1
        function A_dim_1ValueChanged(app, event)
            % n
            value = app.A_dim_1.Value;
            app.A_dim_2.Value = value;
            app.B_dim_1.Value = value;
            app.C_dim_2.Value = value;
            refresh_output(app, event)
        end

        % Value changed function: A_dim_2
        function A_dim_2ValueChanged(app, event)
            % n
            value = app.A_dim_2.Value;
            app.A_dim_1.Value = value;
            app.B_dim_1.Value = value;
            app.C_dim_2.Value = value;
            refresh_output(app, event)
        end

        % Value changed function: B_dim_1
        function B_dim_1ValueChanged(app, event)
            % n
            value = app.B_dim_1.Value;
            app.A_dim_1.Value = value;
            app.A_dim_2.Value = value;
            app.C_dim_2.Value = value;
            refresh_output(app, event)
        end

        % Value changed function: B_dim_2
        function B_dim_2ValueChanged(app, event)
            % m
            value = app.B_dim_2.Value;
            app.K_dim_1.Value = value;
            refresh_output(app, event)
        end

        % Value changed function: K_dim_1
        function K_dim_1ValueChanged(app, event)
            % m
            value = app.K_dim_1.Value;
            app.B_dim_2.Value = value;
            refresh_output(app, event)
        end

        % Value changed function: K_dim_2
        function K_dim_2ValueChanged(app, event)
            % p
            value = app.K_dim_2.Value;
            app.C_dim_1.Value = value;
            refresh_output(app, event)
        end

        % Value changed function: C_dim_1
        function C_dim_1ValueChanged(app, event)
            % p
            value = app.C_dim_1.Value;
            app.K_dim_2.Value = value;
            refresh_output(app, event)
        end

        % Value changed function: C_dim_2
        function C_dim_2ValueChanged(app, event)
            % n
            value = app.C_dim_2.Value;
            app.A_dim_1.Value = value;
            app.A_dim_2.Value = value;
            app.B_dim_1.Value = value;
            refresh_output(app, event)
        end

        % Value changed function: Input_A
        function Input_AValueChanged(app, event)
            value = app.Input_A.Value;
            
            if (app.A_dim_1.Value ~= 0 && app.A_dim_2.Value ~= 0)
                app.A = parse_matrix(app, char(join(value)), [app.A_dim_1.Value, app.A_dim_2.Value]);
                
                app.Output_A.ColumnWidth = num2cell(zeros(1, app.A_dim_2.Value) + 30);
                app.Output_A.Data = app.A;
            end
        end

        % Value changed function: Input_B
        function Input_BValueChanged(app, event)
            value = app.Input_B.Value;
            
            if (app.B_dim_1.Value ~= 0 && app.B_dim_2.Value ~= 0)
                app.B = parse_matrix(app, char(join(value)), [app.B_dim_1.Value, app.B_dim_2.Value]);
                
                app.Output_B.ColumnWidth = num2cell(zeros(1, app.B_dim_2.Value) + 30);
                app.Output_B.Data = app.B;
            end
        end

        % Value changed function: Input_K
        function Input_KValueChanged(app, event)
            value = app.Input_K.Value;
            m = parse_matrix(app, char(join(value)), [app.K_dim_1.Value, app.K_dim_2.Value]);
            m(m ~= 0) = 1;
            
            if (app.K_dim_1.Value ~= 0 && app.K_dim_2.Value ~= 0)
                app.K = m;
            
                app.Output_K.ColumnWidth = num2cell(zeros(1, app.K_dim_2.Value) + 30);
                app.Output_K.Data = app.K;
            end
        end

        % Value changed function: Input_C
        function Input_CValueChanged(app, event)
            value = app.Input_C.Value;
            
            if (app.C_dim_1.Value ~= 0 && app.C_dim_2.Value ~= 0)
                app.C = parse_matrix(app, char(join(value)), [app.C_dim_1.Value, app.C_dim_2.Value]);
                
                app.Output_C.ColumnWidth = num2cell(zeros(1, app.C_dim_2.Value) + 30);
                app.Output_C.Data = app.C;
            end
        end

        % Selection changed function: InputorderButtonGroup
        function InputorderButtonGroupSelectionChanged(app, event)
            %selectedButton = app.InputorderButtonGroup.SelectedObject;
            refresh_output(app, event)
        end

        % Button pushed function: GenerateSMTLIBv2Button
        function GenerateSMTLIBv2ButtonPushed(app, event)
            if (app.A_dim_1.Value == 0 || app.A_dim_2.Value == 0) || ...
                    (app.B_dim_1.Value == 0 || app.B_dim_2.Value == 0) || ...
                    (app.K_dim_1.Value == 0 || app.K_dim_2.Value == 0) || ...
                    (app.C_dim_1.Value == 0 || app.C_dim_2.Value == 0)
                uialert(app.UIFigure, 'One of the matrices is not defined (dimension 0)', 'Error');
            else
                K_matrix = sym('K', [app.K_dim_1.Value app.K_dim_2.Value]);
                K_matrix(app.K == 0) = 0;
                
                z_matrix = sym('z', [app.C_dim_2.Value 1]);
                
                M = (app.A + app.B*K_matrix*app.C) * z_matrix;
                
                app.OutputTextArea.Value = generate_smt(M);
            end
            
            
        end

        % Button pushed function: CopytoclipboardButton
        function CopytoclipboardButtonPushed(app, event)
            clipboard('copy', strjoin(app.OutputTextArea.Value, '\n'));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DimensionsPanel = uipanel(app.UIFigure);
            app.DimensionsPanel.Title = 'Dimensions';
            app.ALabel = uilabel(app.DimensionsPanel);
            app.ALabel.Text = 'A:';
            app.A_dim_1 = uieditfield(app.DimensionsPanel, 'numeric');
            app.A_dim_1.Limits = [0 Inf];
            app.A_dim_1.RoundFractionalValues = 'on';
            app.A_dim_1.ValueChangedFcn = createCallbackFcn(app, @A_dim_1ValueChanged, true);
            app.xEditFieldLabel = uilabel(app.DimensionsPanel);
            app.xEditFieldLabel.Text = 'x';
            app.A_dim_2 = uieditfield(app.DimensionsPanel, 'numeric');
            app.A_dim_2.Limits = [0 Inf];
            app.A_dim_2.RoundFractionalValues = 'on';
            app.A_dim_2.ValueChangedFcn = createCallbackFcn(app, @A_dim_2ValueChanged, true);
            app.BLabel = uilabel(app.DimensionsPanel);
            app.BLabel.Text = 'B:';
            app.B_dim_1 = uieditfield(app.DimensionsPanel, 'numeric');
            app.B_dim_1.ValueChangedFcn = createCallbackFcn(app, @B_dim_1ValueChanged, true);
            app.xEditField_3Label = uilabel(app.DimensionsPanel);
            app.xEditField_3Label.Text = 'x';
            app.B_dim_2 = uieditfield(app.DimensionsPanel, 'numeric');
            app.B_dim_2.ValueChangedFcn = createCallbackFcn(app, @B_dim_2ValueChanged, true);
            app.CLabel = uilabel(app.DimensionsPanel);
            app.CLabel.Text = 'C:';
            app.C_dim_1 = uieditfield(app.DimensionsPanel, 'numeric');
            app.C_dim_1.ValueChangedFcn = createCallbackFcn(app, @C_dim_1ValueChanged, true);
            app.xEditField_4Label = uilabel(app.DimensionsPanel);
            app.xEditField_4Label.Text = 'x';
            app.C_dim_2 = uieditfield(app.DimensionsPanel, 'numeric');
            app.C_dim_2.ValueChangedFcn = createCallbackFcn(app, @C_dim_2ValueChanged, true);
            app.KLabel = uilabel(app.DimensionsPanel);
            app.KLabel.Text = 'K:';
            app.K_dim_1 = uieditfield(app.DimensionsPanel, 'numeric');
            app.K_dim_1.ValueChangedFcn = createCallbackFcn(app, @K_dim_1ValueChanged, true);
            app.xEditField_5Label = uilabel(app.DimensionsPanel);
            app.xEditField_5Label.Text = 'x';
            app.K_dim_2 = uieditfield(app.DimensionsPanel, 'numeric');
            app.K_dim_2.ValueChangedFcn = createCallbackFcn(app, @K_dim_2ValueChanged, true);
            app.GenerateSMTLIBv2Button = uibutton(app.UIFigure, 'push');
            app.GenerateSMTLIBv2Button.ButtonPushedFcn = createCallbackFcn(app, @GenerateSMTLIBv2ButtonPushed, true);
            app.GenerateSMTLIBv2Button.Text = {'Generate'; 'SMT-LIBv2'};
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ATab = uitab(app.TabGroup);
            app.ATab.Title = 'A';
            app.InputmatrixATextAreaLabel = uilabel(app.ATab);
            app.InputmatrixATextAreaLabel.Text = 'Input matrix A:';
            app.Input_A = uitextarea(app.ATab);
            app.Input_A.ValueChangedFcn = createCallbackFcn(app, @Input_AValueChanged, true);
            app.BTab = uitab(app.TabGroup);
            app.BTab.Title = 'B';
            app.InputmatrixBTextAreaLabel = uilabel(app.BTab);
            app.InputmatrixBTextAreaLabel.Text = 'Input matrix B:';
            app.Input_B = uitextarea(app.BTab);
            app.Input_B.ValueChangedFcn = createCallbackFcn(app, @Input_BValueChanged, true);
            app.KTab = uitab(app.TabGroup);
            app.KTab.Title = 'K';
            app.InputmatrixKTextAreaLabel = uilabel(app.KTab);
            app.InputmatrixKTextAreaLabel.Text = 'Input matrix K:';
            app.Input_K = uitextarea(app.KTab);
            app.Input_K.ValueChangedFcn = createCallbackFcn(app, @Input_KValueChanged, true);
            app.CTab = uitab(app.TabGroup);
            app.CTab.Title = 'C';
            app.InputmatrixCTextAreaLabel = uilabel(app.CTab);
            app.InputmatrixCTextAreaLabel.Text = 'Input matrix C:';
            app.Input_C = uitextarea(app.CTab);
            app.Input_C.ValueChangedFcn = createCallbackFcn(app, @Input_CValueChanged, true);
            app.OutputTextArea = uitextarea(app.UIFigure);
            app.InputorderButtonGroup = uibuttongroup(app.UIFigure);
            app.InputorderButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InputorderButtonGroupSelectionChanged, true);
            app.InputorderButtonGroup.Title = 'Input order';
            app.RowmajorButton = uiradiobutton(app.InputorderButtonGroup);
            app.RowmajorButton.Text = 'Row-major';
            app.RowmajorButton.Value = true;
            app.ColumnmajorButton = uiradiobutton(app.InputorderButtonGroup);
            app.ColumnmajorButton.Text = 'Column-major';
            app.InstructionsPanel = uipanel(app.UIFigure);
            app.InstructionsPanel.Title = 'Instructions';
            app.ThistoolgeneratesSMTLIBv2Label = uilabel(app.InstructionsPanel);
            app.ThistoolgeneratesSMTLIBv2Label.Text = {'Enter the dimensions of the matrices.'; ''; 'Use the inputs on the right to define values for each of'; 'the matrices. Any format with whitespace between'; 'numeric values is acceptable. Entries can be read into'; 'the matrix in either row-major or column-major order.'; ''; 'As matrices are entered, you will see them appear in the'; 'tables below. The matrix K has entries 0 (must be 0) or '; '1 (unrestricted).'; ''; 'After each matrix is entered, click the button to generate'; 'SMT-LIBv2 code describing the system. You can then'; 'copy to the system clipboard.'};
            app.Output_A = uitable(app.UIFigure);
            app.Output_A.ColumnName = {''};
            app.Output_A.RowName = {};
            app.Output_B = uitable(app.UIFigure);
            app.Output_B.ColumnName = {''};
            app.Output_B.RowName = {};
            app.Output_K = uitable(app.UIFigure);
            app.Output_K.ColumnName = {''};
            app.Output_K.RowName = {};
            app.Output_C = uitable(app.UIFigure);
            app.Output_C.ColumnName = {''};
            app.Output_C.RowName = {};
            app.ALabel_2 = uilabel(app.UIFigure);
            app.ALabel_2.Text = 'A';
            app.BLabel_2 = uilabel(app.UIFigure);
            app.BLabel_2.Text = 'B';
            app.KLabel_2 = uilabel(app.UIFigure);
            app.KLabel_2.Text = 'K';
            app.CLabel_2 = uilabel(app.UIFigure);
            app.CLabel_2.Text = 'C';
            app.CopytoclipboardButton = uibutton(app.UIFigure, 'push');
            app.CopytoclipboardButton.ButtonPushedFcn = createCallbackFcn(app, @CopytoclipboardButtonPushed, true);
            app.CopytoclipboardButton.Text = {'Copy to'; 'clipboard'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = smt_generator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end